classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        HosptialNameDropDownLabel   matlab.ui.control.Label
        HosptialNameDropDown        matlab.ui.control.DropDown
        PregencytypeDropDownLabel   matlab.ui.control.Label
        PregencytypeDropDown        matlab.ui.control.DropDown
        PridictButton               matlab.ui.control.Button
        AgeEditFieldLabel           matlab.ui.control.Label
        AgeEditField                matlab.ui.control.NumericEditField
        UIAxes_2                    matlab.ui.control.UIAxes
        DeliveryMonthDropDownLabel  matlab.ui.control.Label
        DeliveryMonthDropDown       matlab.ui.control.DropDown
        PredictionLampLabel         matlab.ui.control.Label
        PredictionLamp              matlab.ui.control.Lamp
        SelectCTGButton             matlab.ui.control.Button
        FileLabel                   matlab.ui.control.Label
        PlotButton                  matlab.ui.control.Button
        Lamp2                       matlab.ui.control.Lamp
        EvaluateButton              matlab.ui.control.Button
        ClearButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        loc 
        X
        Y
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PridictButton
        function PridictButtonPushed(app, event)
        app.Lamp2.Color = 'yellow';
        r = pre(app.loc);
        if(r==0)
            app.PredictionLamp.Color = 'green';
        else 
            app.PredictionLamp.Color = 'red';
        end
        end

        % Button pushed function: SelectCTGButton
        function SelectCTGButtonPushed(app, event)
            [file,path] = uigetfile;
            app.loc = strcat(path,file);
            textLabel = sprintf('%s', file);
            app.FileLabel.Text = textLabel;
            app.Lamp2.Color = 'green'
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            data = downsample([csvread(app.loc,2,1)],4);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,data(1:length(data),1))
            hold(app.UIAxes_2,'on')
            plot(app.UIAxes_2,data(1:length(data),2))  
            app.Lamp2.Color = 'blue'
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            data = csvread(app.loc,2,1);
            [X,Y]=getout(app.loc);
            Y=Y.';
            %plot(app.UIAxes,data(1:length(data),1))
            scatter(app.UIAxes,X,Y(1:length(Y),1),2,'r','.')
            hold(app.UIAxes,'off')
            scatter(app.UIAxes_2,X,Y(1:length(Y),2),2,'r','.')
            hold(app.UIAxes_2,'off')
            app.Lamp2.Color = 'cyan'
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes_2)
            cla(app.UIAxes)
            app.PredictionLamp.Color = 'white';
            app.Lamp2.Color = 'white';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Fetal Heart Rate')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'b.p.m')
            app.HosptialNameDropDownLabel = uilabel(app.UIFigure);
            app.HosptialNameDropDownLabel.Text = 'Hosptial Name';
            app.HosptialNameDropDown = uidropdown(app.UIFigure);
            app.HosptialNameDropDown.Items = {'Morden Hosptial', 'City Hosptial', 'Jeevak Hosptial', 'Prime Hosptial'};
            app.HosptialNameDropDown.Value = 'Morden Hosptial';
            app.PregencytypeDropDownLabel = uilabel(app.UIFigure);
            app.PregencytypeDropDownLabel.Text = 'Pregency type';
            app.PregencytypeDropDown = uidropdown(app.UIFigure);
            app.PregencytypeDropDown.Items = {'singlton', 'other', ''};
            app.PregencytypeDropDown.Value = 'singlton';
            app.PridictButton = uibutton(app.UIFigure, 'push');
            app.PridictButton.ButtonPushedFcn = createCallbackFcn(app, @PridictButtonPushed, true);
            app.PridictButton.Text = 'Pridict';
            app.AgeEditFieldLabel = uilabel(app.UIFigure);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Utrine Contraction')
            xlabel(app.UIAxes_2, 'time')
            ylabel(app.UIAxes_2, 'mmHg')
            app.DeliveryMonthDropDownLabel = uilabel(app.UIFigure);
            app.DeliveryMonthDropDownLabel.Text = 'Delivery Month';
            app.DeliveryMonthDropDown = uidropdown(app.UIFigure);
            app.DeliveryMonthDropDown.Items = {'None', '5', '6', '7', '8', '9'};
            app.DeliveryMonthDropDown.Value = 'None';
            app.PredictionLampLabel = uilabel(app.UIFigure);
            app.PredictionLampLabel.Text = 'Prediction';
            app.PredictionLamp = uilamp(app.UIFigure);
            app.SelectCTGButton = uibutton(app.UIFigure, 'push');
            app.SelectCTGButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCTGButtonPushed, true);
            app.SelectCTGButton.Text = 'Select CTG';
            app.FileLabel = uilabel(app.UIFigure);
            app.FileLabel.Text = 'File';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Lamp2 = uilamp(app.UIFigure);
            app.EvaluateButton = uibutton(app.UIFigure, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end